classdef FER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FacialExpressionRecognitionUIFigure  matlab.ui.Figure
        SelectanimageButton  matlab.ui.control.Button
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes4              matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: SelectanimageButton
        function main2(app, event)
load('Image_labels_CK_plus_last_3_of_each_folder.mat');
load('CK_plus_Boundaries_of_check_region.mat');
load('knn_model.mat');
%load('Feature_Histograms.mat');
region_x=10;
region_y=10;
 app.UIAxes2.cla;
 app.UIAxes3.cla;
 app.UIAxes4.cla;
 title(app.UIAxes2,'');
 title(app.UIAxes3,'');
[file,path] = uigetfile('*.*');
if isequal(file,0)
   disp('User selected Cancel');
   return;
else
   disp(['User selected ', fullfile(path,file)]);
   
end
figure(app.FacialExpressionRecognitionUIFigure);
%% Feature Vector Generation
fprintf('Accessing Image# %s..\n',file);
img=imread(fullfile(path,file));

[r,c,ch]=size(img);
if(ch>1)
    img=rgb2gray(img); 
 end
if(r<380 || c<320)

    str='Image size cannot be less than 380x320. Try again';
    disp(str); 
    imshow(insertText(zeros(200,2400),[30 30],str,'BoxCOlor','c','BoxOpacity',0.05,'FontSize',90,'TextColor','r'),'parent', app.UIAxes4);
    return;
elseif ( r>380 && c>320)
    cropping_points=detect_cropping_points(img);  %%% Get the cropping region
        size_of_cropping_points=size(cropping_points,1);
        idx=size_of_cropping_points;
        if idx<1
            str='Image is not suitable. Try again';
            disp(str); 
            imshow(insertText(zeros(200,2400),[30 30],str,'BoxCOlor','c','BoxOpacity',0.05,'FontSize',90,'TextColor','r'),'parent', app.UIAxes4);
            return;
        end
        cropping_points(:,4)=cropping_points(:,4)+30;
        cropping_points(:,3)=cropping_points(:,3)-35;
        cropping_points(:,1)=cropping_points(:,1)+15;
        cropping_points(:,2)=cropping_points(:,2)+0;      
        img = imcrop(img,cropping_points(idx,:));
end
 
 
 %% 
 img=imresize(img,[380 320]);
 
 %%
boundary=FindCheek(img);
left_eyebrow=boundary(1);
right_eyebrow=boundary(2);
lower_eye=boundary(3);
upper_lip=boundary(4);
Feature_Histogram=PTP(img,left_eyebrow,right_eyebrow,lower_eye,upper_lip,region_x,region_y);


%% Classification

   %disp('KNN Testing...');
   experiment_result = predict(knn_model, Feature_Histogram);

   imshow(img,'parent', app.UIAxes3); 
   title(app.UIAxes3,'Input Image');
   if experiment_result==1
       fprintf('Expression: Angry\n');

       imshow(insertText(zeros(700,600),[140 250],'Angry','BoxCOlor','c','BoxOpacity',0.05,'FontSize',80,'TextColor','g'),'parent', app.UIAxes2);
   elseif  experiment_result==2
       fprintf('Expression: Contempt\n');

       imshow(insertText(zeros(700,600),[100 250],'Contempt','BoxCOlor','c','BoxOpacity',0.05,'FontSize',80,'TextColor','g'),'parent', app.UIAxes2);
   elseif  experiment_result==3
       fprintf('Expression: Disgust\n');

       imshow(insertText(zeros(700,600),[100 250],'Disgust','BoxCOlor','c','BoxOpacity',0.05,'FontSize',80,'TextColor','g'),'parent', app.UIAxes2);
   elseif  experiment_result==4
       fprintf('Expression: Fear\n');

       imshow(insertText(zeros(700,600),[180 250],'Fear','BoxCOlor','c','BoxOpacity',0.05,'FontSize',80,'TextColor','g'),'parent', app.UIAxes2);
   elseif  experiment_result==5
       fprintf('Expression: Happy\n');
     
       imshow(insertText(zeros(700,600),[110 250],'Happy','BoxCOlor','c','BoxOpacity',0.05,'FontSize',80,'TextColor','g'),'parent', app.UIAxes2);
   elseif  experiment_result==6
       fprintf('Expression: Sad\n');
     
       imshow(insertText(zeros(700,600),[180 250],'Sad','BoxCOlor','c','BoxOpacity',0.05,'FontSize',80,'TextColor','g'),'parent', app.UIAxes2);
   elseif  experiment_result==7
       fprintf('Expression: Surprise\n');
 
       imshow(insertText(zeros(700,600),[100 250],'Surprise','BoxCOlor','c','BoxOpacity',0.05,'FontSize',80,'TextColor','g'),'parent', app.UIAxes2);    
   end
   title(app.UIAxes2,'Expression');


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FacialExpressionRecognitionUIFigure = uifigure;
            app.FacialExpressionRecognitionUIFigure.Name = 'Facial Expression Recognition';
            app.SelectanimageButton = uibutton(app.FacialExpressionRecognitionUIFigure, 'push');
            app.SelectanimageButton.ButtonPushedFcn = createCallbackFcn(app, @main2, true);
            app.SelectanimageButton.Tooltip = {'Click here to select an image from the computer'};
            app.SelectanimageButton.Text = 'Select an image';
            app.UIAxes2 = uiaxes(app.FacialExpressionRecognitionUIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.FacialExpressionRecognitionUIFigure);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.Visible = 'off';
            app.UIAxes4 = uiaxes(app.FacialExpressionRecognitionUIFigure);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.UIAxes4.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FER

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FacialExpressionRecognitionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FacialExpressionRecognitionUIFigure)
        end
    end
end